classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        GridLayout      matlab.ui.container.GridLayout
        force_help_2    matlab.ui.control.Button
        force_help      matlab.ui.control.Button
        ForceBoxY       matlab.ui.control.ListBox
        ListBox_3Label  matlab.ui.control.Label
        Label_8         matlab.ui.control.Label
        EditForceY      matlab.ui.control.NumericEditField
        Label_5         matlab.ui.control.Label
        Label_4         matlab.ui.control.Label
        EditForceX      matlab.ui.control.NumericEditField
        Label_3         matlab.ui.control.Label
        ForceBoxX       matlab.ui.control.ListBox
        Label_2         matlab.ui.control.Label
        BearingType     matlab.ui.control.ListBox
        Label           matlab.ui.control.Label
        ImbalanceBox    matlab.ui.control.CheckBox
        Stability       matlab.ui.control.Button
        Motion          matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Motion
        function MotionPushed(app, event)
            [typeEnum,imbalanceBool,forceXEnum,powerX,...
                forceYEnum,powerY,tForce,deltaT,tMax] =... 
            create_UI_components(app);
            build_moving_graph(typeEnum,tMax,imbalanceBool,forceXEnum,powerX,...
                forceYEnum,powerY,tForce,deltaT)
        end

        % Button pushed function: Stability
        function StabilityPushed(app, event)
            [typeEnum,imbalanceBool,forceXEnum,powerX,...
                forceYEnum,powerY,tForce,deltaT,tMax] =...
            create_UI_components(app);
            build_border_graph(typeEnum,tMax,imbalanceBool,forceXEnum,powerX,...
                forceYEnum,powerY,tForce,deltaT)
        end

        % Button pushed function: force_help
        function force_helpButtonPushed(app, event)
            [typeEnum,imbalanceBool,forceXEnum,powerX,...
                forceYEnum,powerY,tForce,deltaT,tMax] =... 
            create_UI_components(app);
            create_force_graph(forceXEnum, tForce, powerX, deltaT, tMax)
        end

        % Button pushed function: force_help_2
        function force_help_2ButtonPushed(app, event)
            [typeEnum,imbalanceBool,forceXEnum,powerX,...
                forceYEnum,powerY,tForce,deltaT,tMax] =...
            create_UI_components(app);
            create_force_graph(forceYEnum, tForce, powerY, deltaT, tMax)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Motion = uibutton(app.GridLayout, 'push');
            app.Motion.ButtonPushedFcn = createCallbackFcn(app, @MotionPushed, true);
            app.Motion.Text = 'Перемещения';
            app.Stability = uibutton(app.GridLayout, 'push');
            app.Stability.ButtonPushedFcn = createCallbackFcn(app, @StabilityPushed, true);
            app.Stability.Text = {'Область '; 'устойчивости'};
            app.ImbalanceBox = uicheckbox(app.GridLayout);
            app.ImbalanceBox.Text = 'Дисбаланс';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = 'Подшипник';
            app.BearingType = uilistbox(app.GridLayout);
            app.BearingType.Items = {'Эллиптический', 'Эллиптический с легким ротором', 'Эллиптический с "нулевым" ротором', 'Сегментный', 'Сегментный с легким ротором', 'Сегментный с "нулевым" ротором'};
            app.BearingType.Value = 'Эллиптический';
            app.Label_2 = uilabel(app.GridLayout);
            app.Label_2.Text = 'Возмущение';
            app.ForceBoxX = uilistbox(app.GridLayout);
            app.ForceBoxX.Items = {'Отсутствует', 'Линейное', 'Импульсное'};
            app.ForceBoxX.Value = 'Отсутствует';
            app.Label_3 = uilabel(app.GridLayout);
            app.Label_3.Text = 'Сила возмущения';
            app.EditForceX = uieditfield(app.GridLayout, 'numeric');
            app.EditForceX.Limits = [0 Inf];
            app.EditForceX.Value = 59.69;
            app.Label_4 = uilabel(app.GridLayout);
            app.Label_4.Text = 'Горизонтальная ось';
            app.Label_5 = uilabel(app.GridLayout);
            app.Label_5.Text = 'Вертикальная ось';
            app.EditForceY = uieditfield(app.GridLayout, 'numeric');
            app.EditForceY.Limits = [0 Inf];
            app.EditForceY.Value = 59.69;
            app.Label_8 = uilabel(app.GridLayout);
            app.Label_8.Text = 'Сила возмущения';
            app.ListBox_3Label = uilabel(app.GridLayout);
            app.ListBox_3Label.Text = 'Возмущение';
            app.ForceBoxY = uilistbox(app.GridLayout);
            app.ForceBoxY.Items = {'Отсутствует', 'Линейное', 'Импульсное'};
            app.ForceBoxY.Value = 'Отсутствует';
            app.force_help = uibutton(app.GridLayout, 'push');
            app.force_help.ButtonPushedFcn = createCallbackFcn(app, @force_helpButtonPushed, true);
            app.force_help.Text = '?';
            app.force_help_2 = uibutton(app.GridLayout, 'push');
            app.force_help_2.ButtonPushedFcn = createCallbackFcn(app, @force_help_2ButtonPushed, true);
            app.force_help_2.Text = '?';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end