classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        GridLayout      matlab.ui.container.GridLayout
        ForceBoxY       matlab.ui.control.ListBox
        ListBox_3Label  matlab.ui.control.Label
        Label_8         matlab.ui.control.Label
        EditForceY      matlab.ui.control.NumericEditField
        Label_5         matlab.ui.control.Label
        Label_4         matlab.ui.control.Label
        EditForceX      matlab.ui.control.NumericEditField
        Label_3         matlab.ui.control.Label
        ForceBoxX       matlab.ui.control.ListBox
        Label_2         matlab.ui.control.Label
        BearingType     matlab.ui.control.ListBox
        Label           matlab.ui.control.Label
        ImbalanceBox    matlab.ui.control.CheckBox
        Button_2        matlab.ui.control.Button
        Button          matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            [typeEnum,imbalanceBool,forceXEnum,powerX,...
                forceYEnum,powerY] = createUIComponents(app);
            Moving_graph(typeEnum,300,imbalanceBool,forceXEnum,powerX,...
                forceYEnum,powerY)
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            [typeEnum,imbalanceBool,forceXEnum,powerX,...
                forceYEnum,powerY] = createUIComponents(app);
            Border_graph(typeEnum,300,imbalanceBool,forceXEnum,powerX,...
                forceYEnum,powerY)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Button = uibutton(app.GridLayout, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = 'Перемещения';
            app.Button_2 = uibutton(app.GridLayout, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = {'Область '; 'устойчивости'};
            app.ImbalanceBox = uicheckbox(app.GridLayout);
            app.ImbalanceBox.Text = 'Дисбаланс';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = 'Подшипник';
            app.BearingType = uilistbox(app.GridLayout);
            app.BearingType.Items = {'Эллиптический', 'Сегментный'};
            app.BearingType.Value = 'Эллиптический';
            app.Label_2 = uilabel(app.GridLayout);
            app.Label_2.Text = 'Возмущение';
            app.ForceBoxX = uilistbox(app.GridLayout);
            app.ForceBoxX.Items = {'Отсутствует', 'Линейное', 'Импульсное'};
            app.ForceBoxX.Value = 'Отсутствует';
            app.Label_3 = uilabel(app.GridLayout);
            app.Label_3.Text = 'Сила возмущения';
            app.EditForceX = uieditfield(app.GridLayout, 'numeric');
            app.EditForceX.Limits = [0 Inf];
            app.EditForceX.Value = 59.69;
            app.Label_4 = uilabel(app.GridLayout);
            app.Label_4.Text = 'Горизонтальная ось';
            app.Label_5 = uilabel(app.GridLayout);
            app.Label_5.Text = 'Вертикальная ось';
            app.EditForceY = uieditfield(app.GridLayout, 'numeric');
            app.EditForceY.Limits = [0 Inf];
            app.EditForceY.Value = 59.69;
            app.Label_8 = uilabel(app.GridLayout);
            app.Label_8.Text = 'Сила возмущения';
            app.ListBox_3Label = uilabel(app.GridLayout);
            app.ListBox_3Label.Text = 'Возмущение';
            app.ForceBoxY = uilistbox(app.GridLayout);
            app.ForceBoxY.Items = {'Отсутствует', 'Линейное', 'Импульсное'};
            app.ForceBoxY.Value = 'Отсутствует';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end